classdef gui < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to app components
    properties (Access = private, Transient, NonCopyable)
        GridLayout            matlab.ui.container.GridLayout
        MoveRobotButton       matlab.ui.control.Button
        ResetButton           matlab.ui.control.Button
        GridLayout3           matlab.ui.container.GridLayout
        ForceEditField        matlab.ui.control.NumericEditField
        EnterexternalforceontheendeffectorLabel  matlab.ui.control.Label
        ExternalforceLabel    matlab.ui.control.Label
        PositioncontrolLabel  matlab.ui.control.Label
        GridLayout2_2         matlab.ui.container.GridLayout
        YEditField_2          matlab.ui.control.NumericEditField
        YLabel_2              matlab.ui.control.Label
        PEditField            matlab.ui.control.NumericEditField
        PLabel                matlab.ui.control.Label
        REditField            matlab.ui.control.NumericEditField
        RLabel                matlab.ui.control.Label
        GridLayout2           matlab.ui.container.GridLayout
        ZEditField            matlab.ui.control.NumericEditField
        ZLabel                matlab.ui.control.Label
        YEditField            matlab.ui.control.NumericEditField
        YLabel                matlab.ui.control.Label
        XEditField            matlab.ui.control.NumericEditField
        XLabel                matlab.ui.control.Label
    end

    
    methods (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            % clear, close all, clc;
            comp.robot = make_robot();
            % Starting here, adjust to use IK to convert find target joint
            % positions (as targetQ) from starting values currentQ. This
            % will make it easier to plot movement.
            % IK SOLVER
            % Ends here. params should take in the two joint arrays (or
            % just adjust to take them in as pure variables since thats
            % probably easier
            target = [comp.x, comp.y, comp.z, comp.roll,comp.pitch,comp.yaw];
            [jointPos, jointVel, jointAcc, jointTau, t_s]  = move_robot(comp.robot, comp.currentQ, target, comp.force);
            if ~isempty(jointPos)
                p0 = subplot(4,2,[1,3,5,7]);
                hold off;
                set(gcf, 'Name', 'PUMA 560 Simulator');
                comp.robot.plot(jointPos(:,1)');
                p1 = subplot(4,2,2);
                cla(p1);
                hold on, grid on;
                l1p=line(1,jointPos(1,1), 'r', 'Linewidth', 2);
                l2p=line(1,jointPos(2,1), 'g', 'Linewidth', 2);
                l3p=line(1,jointPos(3,1), 'b', 'Linewidth', 2);
                l4p=line(1,jointPos(4,1), 'c', 'Linewidth', 2);
                l5p=line(1,jointPos(5,1), 'm', 'Linewidth', 2);
                l6p=line(1,jointPos(6,1), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Positions');
                xlabel("Time (s)");
                ylabel("Position (rad)");
                % set(gca, 'FontSize', 14);
                % hold off;
                p2 = subplot(4,2,4);
                cla(p2);
                hold on, grid on;
                l1v=line(1,jointVel(1,1), 'r', 'Linewidth', 2);
                l2v=line(1,jointVel(2,1), 'g', 'Linewidth', 2);
                l3v=line(1,jointVel(3,1), 'b', 'Linewidth', 2);
                l4v=line(1,jointVel(4,1), 'c', 'Linewidth', 2);
                l5v=line(1,jointVel(5,1), 'm', 'Linewidth', 2);
                l6v=line(1,jointVel(6,1), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Velocities');
                xlabel("Time (s)");
                ylabel("Velocity (rad/s)");
                % set(gca, 'FontSize', 14);
                % hold off;
                p3 = subplot(4,2,6);
                cla(p3);
                hold on, grid on;
                l1a=line(1,jointAcc(1,1), 'r', 'Linewidth', 2);
                l2a=line(1,jointAcc(2,1), 'g', 'Linewidth', 2);
                l3a=line(1,jointAcc(3,1), 'b', 'Linewidth', 2);
                l4a=line(1,jointAcc(4,1), 'c', 'Linewidth', 2);
                l5a=line(1,jointAcc(5,1), 'm', 'Linewidth', 2);
                l6a=line(1,jointAcc(6,1), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Accelerations');
                xlabel("Time (s)");
                ylabel("Acceleration (rad/s^2)");
                % set(gca, 'FontSize', 14);
                % hold off;
                p4 = subplot(4,2,8);
                cla(p4);
                hold on, grid on;
                l1t=line(1,jointTau(1,1), 'r', 'Linewidth', 2);
                l2t=line(1,jointTau(2,1), 'g', 'Linewidth', 2);
                l3t=line(1,jointTau(3,1), 'b', 'Linewidth', 2);
                l4t=line(1,jointTau(4,1), 'c', 'Linewidth', 2);
                l5t=line(1,jointTau(5,1), 'm', 'Linewidth', 2);
                l6t=line(1,jointTau(6,1), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Torques');
                xlabel("Time (s)");
                ylabel("Torque (Nm)");
                title('Joint Torques');
                % set(gca, 'FontSize', 14);
                hold off;
                comp.currentQ = jointPos(:, end);
                for ii = 11:10:size(jointPos,2)
                    comp.robot.plot(jointPos(:,ii)');
                    % Pos
                    l1p.XData = [l1p.XData ii];
                    l1p.YData = [l1p.YData jointPos(1,ii)];
                    l2p.XData = [l2p.XData ii];
                    l2p.YData = [l2p.YData jointPos(2,ii)];
                    l3p.XData = [l3p.XData ii];
                    l3p.YData = [l3p.YData jointPos(3,ii)];
                    l4p.XData = [l4p.XData ii];
                    l4p.YData = [l4p.YData jointPos(4,ii)];
                    l5p.XData = [l5p.XData ii];
                    l5p.YData = [l5p.YData jointPos(5,ii)];
                    l6p.XData = [l6p.XData ii];
                    l6p.YData = [l6p.YData jointPos(6,ii)];
                    
                    % Vel
                    l1v.XData = [l1v.XData ii];
                    l1v.YData = [l1v.YData jointVel(1,ii)];
                    l2v.XData = [l2v.XData ii];
                    l2v.YData = [l2v.YData jointVel(2,ii)];
                    l3v.XData = [l3v.XData ii];
                    l3v.YData = [l3v.YData jointVel(3,ii)];
                    l4v.XData = [l4v.XData ii];
                    l4v.YData = [l4v.YData jointVel(4,ii)];
                    l5v.XData = [l5v.XData ii];
                    l5v.YData = [l5v.YData jointVel(5,ii)];
                    l6v.XData = [l6v.XData ii];
                    l6v.YData = [l6v.YData jointVel(6,ii)];

                    % Acc
                    l1a.XData = [l1a.XData ii];
                    l1a.YData = [l1a.YData jointAcc(1,ii)];
                    l2a.XData = [l2a.XData ii];
                    l2a.YData = [l2a.YData jointAcc(2,ii)];
                    l3a.XData = [l3a.XData ii];
                    l3a.YData = [l3a.YData jointAcc(3,ii)];
                    l4a.XData = [l4a.XData ii];
                    l4a.YData = [l4a.YData jointAcc(4,ii)];
                    l5a.XData = [l5a.XData ii];
                    l5a.YData = [l5a.YData jointAcc(5,ii)];
                    l6a.XData = [l6a.XData ii];
                    l6a.YData = [l6a.YData jointAcc(6,ii)];

                    % Tau
                    l1t.XData = [l1t.XData ii];
                    l1t.YData = [l1t.YData jointTau(1,ii)];
                    l2t.XData = [l2t.XData ii];
                    l2t.YData = [l2t.YData jointTau(2,ii)];
                    l3t.XData = [l3t.XData ii];
                    l3t.YData = [l3t.YData jointTau(3,ii)];
                    l4t.XData = [l4t.XData ii];
                    l4t.YData = [l4t.YData jointTau(4,ii)];
                    l5t.XData = [l5t.XData ii];
                    l5t.YData = [l5t.YData jointTau(5,ii)];
                    l6t.XData = [l6t.XData ii];
                    l6t.YData = [l6t.YData jointTau(6,ii)];
                end
            else
                errordlg('\fontsize{30} Inverse kinematics failed for that pose', 'IKError', 'modal')
            end
        end

        % Value changed function: XEditField
        function updateX(comp, event)
            comp.x = comp.XEditField.Value;
            
        end

        % Value changed function: YEditField
        function updateY(comp, event)
            comp.y = comp.YEditField.Value;
            
        end

        % Value changed function: ZEditField
        function updateZ(comp, event)
            comp.z = comp.ZEditField.Value;
            
        end

        % Value changed function: REditField
        function updateRoll(comp, event)
            comp.roll = comp.REditField.Value;
            
        end

        % Value changed function: PEditField
        function updatePitch(comp, event)
            comp.pitch = comp.PEditField.Value;
        end

        % Value changed function: YEditField_2
        function updateYaw(comp, event)
            comp.yaw = comp.YEditField_2.Value;
        end

        % Value changed function: ForceEditField
        function updateForce(comp, event)
            comp.force = comp.ForceEditField.Value;
            
        end

        % Button pushed function: ResetButton
        function resetSim(comp, event)
            comp.robot = make_robot();
            comp.x = 0.4521;
            comp.y = -0.15;
            comp.z = 0.4318;
            comp.roll = 0;
            comp.pitch = 0;
            comp.yaw = 0;
            comp.force = 0;
            comp.currentQ = zeros(1,6);
            comp.XEditField.Value = comp.x;
            comp.YEditField.Value = comp.y;
            comp.ZEditField.Value = comp.z;
            comp.REditField.Value = 0;
            comp.PEditField.Value = 0;
            comp.YEditField_2.Value = 0;
            comp.ForceEditField.Value = 0;
            comp.moveRobot()
        end

        % Button pushed function: MoveRobotButton
        function moveRobot(comp, event)
            % Starting here, adjust to use IK to convert find target joint
            % positions (as targetQ) from starting values currentQ. This
            % will make it easier to plot movement.
            % IK SOLVER
            % Ends here. params should take in the two joint arrays (or
            % just adjust to take them in as pure variables since thats
            % probably easier
            target = [comp.x, comp.y, comp.z, comp.roll,comp.pitch,comp.yaw];
            [jointPos, jointVel, jointAcc, jointTau, t_s]  = move_robot(comp.robot, comp.currentQ, target, comp.force);
            if ~isempty(jointPos)
                p0 = subplot(4,2,[1,3,5,7]);
                hold off;
                set(gcf, 'Name', 'PUMA 560 Simulator');
                comp.robot.plot(jointPos(:,1)');
                p1 = subplot(4,2,2);
                cla(p1);
                hold on, grid on;
                l1p=line(1,jointPos(1,1), 'r', 'Linewidth', 2);
                l2p=line(1,jointPos(2,1), 'g', 'Linewidth', 2);
                l3p=line(1,jointPos(3,1), 'b', 'Linewidth', 2);
                l4p=line(1,jointPos(4,1), 'c', 'Linewidth', 2);
                l5p=line(1,jointPos(5,1), 'm', 'Linewidth', 2);
                l6p=line(1,jointPos(6,1), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Positions');
                xlabel("Time (s)");
                ylabel("Position (rad)");
                % set(gca, 'FontSize', 14);
                % hold off;
                p2 = subplot(4,2,4);
                cla(p2);
                hold on, grid on;
                l1v=line(1,jointVel(1,1), 'r', 'Linewidth', 2);
                l2v=line(1,jointVel(2,1), 'g', 'Linewidth', 2);
                l3v=line(1,jointVel(3,1), 'b', 'Linewidth', 2);
                l4v=line(1,jointVel(4,1), 'c', 'Linewidth', 2);
                l5v=line(1,jointVel(5,1), 'm', 'Linewidth', 2);
                l6v=line(1,jointVel(6,1), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Velocities');
                xlabel("Time (s)");
                ylabel("Velocity (rad/s)");
                % set(gca, 'FontSize', 14);
                % hold off;
                p3 = subplot(4,2,6);
                cla(p3);
                hold on, grid on;
                l1a=line(1,jointAcc(1,1), 'r', 'Linewidth', 2);
                l2a=line(1,jointAcc(2,1), 'g', 'Linewidth', 2);
                l3a=line(1,jointAcc(3,1), 'b', 'Linewidth', 2);
                l4a=line(1,jointAcc(4,1), 'c', 'Linewidth', 2);
                l5a=line(1,jointAcc(5,1), 'm', 'Linewidth', 2);
                l6a=line(1,jointAcc(6,1), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Accelerations');
                xlabel("Time (s)");
                ylabel("Acceleration (rad/s^2)");
                % set(gca, 'FontSize', 14);
                % hold off;
                p4 = subplot(4,2,8);
                cla(p4);
                hold on, grid on;
                l1t=line(1,jointTau(1,1), 'r', 'Linewidth', 2);
                l2t=line(1,jointTau(2,1), 'g', 'Linewidth', 2);
                l3t=line(1,jointTau(3,1), 'b', 'Linewidth', 2);
                l4t=line(1,jointTau(4,1), 'c', 'Linewidth', 2);
                l5t=line(1,jointTau(5,1), 'm', 'Linewidth', 2);
                l6t=line(1,jointTau(6,1), 'y', 'Linewidth', 2);
                legend('Joint 1', 'Joint 2', 'Joint 3', 'Joint 4', 'Joint 5', 'Joint 6');
                title('Joint Torques');
                xlabel("Time (s)");
                ylabel("Torque (Nm)");
                title('Joint Torques');
                % set(gca, 'FontSize', 14);
                hold off;
                comp.currentQ = jointPos(:, end);
                for ii = 11:10:size(jointPos,2)
                    comp.robot.plot(jointPos(:,ii)');
                    % Pos
                    l1p.XData = [l1p.XData ii];
                    l1p.YData = [l1p.YData jointPos(1,ii)];
                    l2p.XData = [l2p.XData ii];
                    l2p.YData = [l2p.YData jointPos(2,ii)];
                    l3p.XData = [l3p.XData ii];
                    l3p.YData = [l3p.YData jointPos(3,ii)];
                    l4p.XData = [l4p.XData ii];
                    l4p.YData = [l4p.YData jointPos(4,ii)];
                    l5p.XData = [l5p.XData ii];
                    l5p.YData = [l5p.YData jointPos(5,ii)];
                    l6p.XData = [l6p.XData ii];
                    l6p.YData = [l6p.YData jointPos(6,ii)];
                    
                    % Vel
                    l1v.XData = [l1v.XData ii];
                    l1v.YData = [l1v.YData jointVel(1,ii)];
                    l2v.XData = [l2v.XData ii];
                    l2v.YData = [l2v.YData jointVel(2,ii)];
                    l3v.XData = [l3v.XData ii];
                    l3v.YData = [l3v.YData jointVel(3,ii)];
                    l4v.XData = [l4v.XData ii];
                    l4v.YData = [l4v.YData jointVel(4,ii)];
                    l5v.XData = [l5v.XData ii];
                    l5v.YData = [l5v.YData jointVel(5,ii)];
                    l6v.XData = [l6v.XData ii];
                    l6v.YData = [l6v.YData jointVel(6,ii)];

                    % Acc
                    l1a.XData = [l1a.XData ii];
                    l1a.YData = [l1a.YData jointAcc(1,ii)];
                    l2a.XData = [l2a.XData ii];
                    l2a.YData = [l2a.YData jointAcc(2,ii)];
                    l3a.XData = [l3a.XData ii];
                    l3a.YData = [l3a.YData jointAcc(3,ii)];
                    l4a.XData = [l4a.XData ii];
                    l4a.YData = [l4a.YData jointAcc(4,ii)];
                    l5a.XData = [l5a.XData ii];
                    l5a.YData = [l5a.YData jointAcc(5,ii)];
                    l6a.XData = [l6a.XData ii];
                    l6a.YData = [l6a.YData jointAcc(6,ii)];

                    % Tau
                    l1t.XData = [l1t.XData ii];
                    l1t.YData = [l1t.YData jointTau(1,ii)];
                    l2t.XData = [l2t.XData ii];
                    l2t.YData = [l2t.YData jointTau(2,ii)];
                    l3t.XData = [l3t.XData ii];
                    l3t.YData = [l3t.YData jointTau(3,ii)];
                    l4t.XData = [l4t.XData ii];
                    l4t.YData = [l4t.YData jointTau(4,ii)];
                    l5t.XData = [l5t.XData ii];
                    l5t.YData = [l5t.YData jointTau(5,ii)];
                    l6t.XData = [l6t.XData ii];
                    l6t.YData = [l6t.YData jointTau(6,ii)];
                end
            else
                errordlg('Inverse kinematics failed for that pose', 'IKError', 'modal')
            end
        end
    end

    methods (Access = protected)
        
        % update components, triggered after drawnow
        function update(comp)
            
        end

        % The setup method executes once when user constructs the object
        function setup(comp)

            comp.Position = [1 1 351 303];

            % Create GridLayout
            comp.GridLayout = uigridlayout(comp);
            comp.GridLayout.ColumnWidth = {'fit'};
            comp.GridLayout.RowHeight = {'fit', 'fit', 'fit', 'fit', 'fit', 'fit', 'fit'};
            comp.GridLayout.BackgroundColor = [0.9412 0.9412 0.9412];

            % Create GridLayout2
            comp.GridLayout2 = uigridlayout(comp.GridLayout);
            comp.GridLayout2.ColumnWidth = {'fit', 'fit', 'fit', 'fit', 'fit', 'fit'};
            comp.GridLayout2.RowHeight = {'fit'};
            comp.GridLayout2.Padding = [0 10 0 10];
            comp.GridLayout2.Layout.Row = 2;
            comp.GridLayout2.Layout.Column = 1;

            % Create XLabel
            comp.XLabel = uilabel(comp.GridLayout2);
            comp.XLabel.HorizontalAlignment = 'right';
            comp.XLabel.Layout.Row = 1;
            comp.XLabel.Layout.Column = 1;
            comp.XLabel.Text = 'X:';

            % Create XEditField
            comp.XEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.XEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateX, true);
            comp.XEditField.Layout.Row = 1;
            comp.XEditField.Layout.Column = 2;
            comp.XEditField.Value = 0.4521;

            % Create YLabel
            comp.YLabel = uilabel(comp.GridLayout2);
            comp.YLabel.HorizontalAlignment = 'right';
            comp.YLabel.Layout.Row = 1;
            comp.YLabel.Layout.Column = 3;
            comp.YLabel.Text = 'Y:';

            % Create YEditField
            comp.YEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.YEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateY, true);
            comp.YEditField.Layout.Row = 1;
            comp.YEditField.Layout.Column = 4;
            comp.YEditField.Value = -0.15;

            % Create ZLabel
            comp.ZLabel = uilabel(comp.GridLayout2);
            comp.ZLabel.HorizontalAlignment = 'right';
            comp.ZLabel.Layout.Row = 1;
            comp.ZLabel.Layout.Column = 5;
            comp.ZLabel.Text = 'Z:';

            % Create ZEditField
            comp.ZEditField = uieditfield(comp.GridLayout2, 'numeric');
            comp.ZEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateZ, true);
            comp.ZEditField.Layout.Row = 1;
            comp.ZEditField.Layout.Column = 6;
            comp.ZEditField.Value = 0.4318;

            % Create GridLayout2_2
            comp.GridLayout2_2 = uigridlayout(comp.GridLayout);
            comp.GridLayout2_2.ColumnWidth = {'fit', 'fit', 'fit', 'fit', 'fit', 'fit'};
            comp.GridLayout2_2.RowHeight = {'fit'};
            comp.GridLayout2_2.Padding = [0 10 0 10];
            comp.GridLayout2_2.Layout.Row = 3;
            comp.GridLayout2_2.Layout.Column = 1;

            % Create RLabel
            comp.RLabel = uilabel(comp.GridLayout2_2);
            comp.RLabel.HorizontalAlignment = 'right';
            comp.RLabel.Layout.Row = 1;
            comp.RLabel.Layout.Column = 1;
            comp.RLabel.Text = 'R:';

            % Create REditField
            comp.REditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.REditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateRoll, true);
            comp.REditField.Layout.Row = 1;
            comp.REditField.Layout.Column = 2;

            % Create PLabel
            comp.PLabel = uilabel(comp.GridLayout2_2);
            comp.PLabel.HorizontalAlignment = 'right';
            comp.PLabel.Layout.Row = 1;
            comp.PLabel.Layout.Column = 3;
            comp.PLabel.Text = 'P:';

            % Create PEditField
            comp.PEditField = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.PEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updatePitch, true);
            comp.PEditField.Layout.Row = 1;
            comp.PEditField.Layout.Column = 4;

            % Create YLabel_2
            comp.YLabel_2 = uilabel(comp.GridLayout2_2);
            comp.YLabel_2.HorizontalAlignment = 'right';
            comp.YLabel_2.Layout.Row = 1;
            comp.YLabel_2.Layout.Column = 5;
            comp.YLabel_2.Text = 'Y:';

            % Create YEditField_2
            comp.YEditField_2 = uieditfield(comp.GridLayout2_2, 'numeric');
            comp.YEditField_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateYaw, true);
            comp.YEditField_2.Layout.Row = 1;
            comp.YEditField_2.Layout.Column = 6;

            % Create PositioncontrolLabel
            comp.PositioncontrolLabel = uilabel(comp.GridLayout);
            comp.PositioncontrolLabel.Layout.Row = 1;
            comp.PositioncontrolLabel.Layout.Column = 1;
            comp.PositioncontrolLabel.Text = 'Position control:';

            % Create ExternalforceLabel
            comp.ExternalforceLabel = uilabel(comp.GridLayout);
            comp.ExternalforceLabel.Layout.Row = 4;
            comp.ExternalforceLabel.Layout.Column = 1;
            comp.ExternalforceLabel.Text = 'External force:';

            % Create GridLayout3
            comp.GridLayout3 = uigridlayout(comp.GridLayout);
            comp.GridLayout3.ColumnWidth = {'fit', 'fit'};
            comp.GridLayout3.RowHeight = {'fit'};
            comp.GridLayout3.Layout.Row = 5;
            comp.GridLayout3.Layout.Column = 1;

            % Create EnterexternalforceontheendeffectorLabel
            comp.EnterexternalforceontheendeffectorLabel = uilabel(comp.GridLayout3);
            comp.EnterexternalforceontheendeffectorLabel.HorizontalAlignment = 'right';
            comp.EnterexternalforceontheendeffectorLabel.Layout.Row = 1;
            comp.EnterexternalforceontheendeffectorLabel.Layout.Column = 1;
            comp.EnterexternalforceontheendeffectorLabel.Text = 'Enter external force on the end effector:';

            % Create ForceEditField
            comp.ForceEditField = uieditfield(comp.GridLayout3, 'numeric');
            comp.ForceEditField.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @updateForce, true);
            comp.ForceEditField.Layout.Row = 1;
            comp.ForceEditField.Layout.Column = 2;

            % Create ResetButton
            comp.ResetButton = uibutton(comp.GridLayout, 'push');
            comp.ResetButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @resetSim, true);
            comp.ResetButton.BackgroundColor = [0.6745 0.1686 0.2157];
            comp.ResetButton.FontColor = [1 1 1];
            comp.ResetButton.Layout.Row = 7;
            comp.ResetButton.Layout.Column = 1;
            comp.ResetButton.Text = 'Reset';

            % Create MoveRobotButton
            comp.MoveRobotButton = uibutton(comp.GridLayout, 'push');
            comp.MoveRobotButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @moveRobot, true);
            comp.MoveRobotButton.BackgroundColor = [0.6627 0.6902 0.7176];
            comp.MoveRobotButton.Layout.Row = 6;
            comp.MoveRobotButton.Layout.Column = 1;
            comp.MoveRobotButton.Text = 'Move Robot';
            
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end